classdef ECE_45_Synthesizer_Noise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FilterTypeDropDown            matlab.ui.control.DropDown
        FilterTypeDropDownLabel       matlab.ui.control.Label
        OriginalButton                matlab.ui.control.StateButton
        NoiseSpinner                  matlab.ui.control.Spinner
        NoiseSpinnerLabel             matlab.ui.control.Label
        SaveModifedAudiotoFileButton  matlab.ui.control.Button
        PlotSwitch                    matlab.ui.control.Switch
        PlotSwitchLabel               matlab.ui.control.Label
        PlayFilteredSoundButton       matlab.ui.control.Button
        GenerateAudioPlotButton       matlab.ui.control.Button
        SpeedSpinner                  matlab.ui.control.Spinner
        SpeedSpinnerLabel             matlab.ui.control.Label
        TimeWrapsSlider               matlab.ui.control.Slider
        TimeWrapsSliderLabel          matlab.ui.control.Label
        VolumeSlider                  matlab.ui.control.Slider
        AmplitudeLabel                matlab.ui.control.Label
        PlayUnfilteredSoundButton     matlab.ui.control.Button
        FileLoadedLamp                matlab.ui.control.Lamp
        PlotTypeSwitch                matlab.ui.control.Switch
        PlotTypeLabel                 matlab.ui.control.Label
        LoadFileButton                matlab.ui.control.Button
        ECE45SynthesizerLabel         matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs = 1000;% Sampling rate of the loaded MP3 file
        audioData % Audio data of the loaded MP3 file
        fs2 = 1000;
        
        filterTimeFrequency = 0 % Plot filter in time vs frequency
        amplitudeControl = 100; % Amplitude scaling percentage 
        timeShiftControl = 0; % Time shift in seconds
        speedControl = 1; % Playback speed multiplier
        noiseControl = 0; % Amount of noise
        MODtimeFrequency = 0;
        filter_type = 'Band Pass';
        stateOfMod = 0; % if 0 means original, if 1 means modified

        modifiedAudioData % Audio data with modifications
    end
    
    methods (Access = private)

        function [filter, filteredAudio] = applySelectedFilter(app)
            filterType = app.filter_type;
            defaultLowCutoff = 200;  % Example value in Hz
            defaultHighCutoff = 3000; % Example value in Hz
        
            % Design the filter based on the selected type
            switch filterType
                case 'Low Pass'
                    filter = designfilt('lowpassfir', 'FilterOrder', 20, 'CutoffFrequency', defaultHighCutoff, 'SampleRate', app.fs);
                case 'High Pass'
                    filter = designfilt('highpassfir', 'FilterOrder', 20, 'CutoffFrequency', defaultLowCutoff, 'SampleRate', app.fs);
                case 'Band Pass'
                    filter = designfilt('bandpassfir', 'FilterOrder', 20, 'CutoffFrequency1', defaultLowCutoff, 'CutoffFrequency2', defaultHighCutoff, 'SampleRate', app.fs);
                case 'Band Reject'
                    filter = designfilt('bandstopfir', 'FilterOrder', 20, 'CutoffFrequency1', defaultLowCutoff, 'CutoffFrequency2', defaultHighCutoff, 'SampleRate', app.fs);
            end
        
            if app.stateOfMod == 0
                % Apply the filter
                filteredAudio = filtfilt(filter, app.audioData);
            else 
                filteredAudio = filtfilt(filter, app.modifiedAudioData);
            end
        end
            
        function [shifted_audio, L] = AudioCalculation(app)
            L = length(app.audioData);
            Y = fft(app.audioData);
            
            modifiedY = Y*(app.amplitudeControl/100); 
            modifiedY_time = ifft(modifiedY);

            yt = fft(modifiedY_time);
            samples_to_shift = round(app.timeShiftControl * app.fs);
            if samples_to_shift > 0
                shift_vector = exp(-1j * 2 * pi * (0:length(modifiedY_time)-1)' * samples_to_shift / length(modifiedY_time));
            elseif samples_to_shift < 0
                shift_vector = exp(1j * 2 * pi * (0:length(modifiedY_time)-1)' * abs(samples_to_shift) / length(modifiedY_time));
            else
                shift_vector = 1;
            end

            shifted_audio_fft = yt .* shift_vector;
            shifted_audio = ifft(shifted_audio_fft);

            if length(shifted_audio) < length(modifiedY_time)
                shifted_audio = [shifted_audio; zeros(length(audio) - length(shifted_audio), 1)];
            elseif length(shifted_audio) > length(modifiedY_time)
                shifted_audio = shifted_audio(1:length(modifiedY_time));
            end
            
            if app.noiseControl > 0
                noise_fft = randn(size(shifted_audio)) + 1i * randn(size(shifted_audio));
                noise_fft_scaled = app.noiseControl * noise_fft;
                original_fft = fft(shifted_audio);
                %modified_fft = original_fft .* noise_fft_scaled;
                %noised_sound = ifft(modified_fft);
                modified_fft = original_fft + noise_fft_scaled;
                noised_sound = ifft(modified_fft);  
                noised_sound = noised_sound / max(abs(noised_sound));
                shifted_audio = noised_sound;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Open file dialog for selecting an MP3 file
            [file, path] = uigetfile('*.mp3', 'Select an MP3 file');
            if isequal(file, 0)
                return; % User clicked Cancel
            end
            fullPath = fullfile(path, file);
        
            % Read the audio file
            [app.audioData, app.fs] = audioread(fullPath);
            app.FileLoadedLamp.Color = 'g';

            app.amplitudeControl = app.VolumeSlider.Value;
            app.timeShiftControl = app.TimeWrapsSlider.Value;
            app.fs2 = app.fs * app.SpeedSpinner.Value;
            app.noiseControl = app.NoiseSpinner.Value;

            t = (0:length(app.audioData)-1)/app.fs;
    
            plot(app.UIAxes, t, app.audioData);
            title(app.UIAxes, 'Original Time Domain Signal');
            xlabel(app.UIAxes, 'Time (seconds)');
            ylabel(app.UIAxes, 'Amplitude');
            

        end

        % Value changed function: PlotTypeSwitch
        function PlotTypeSwitchValueChanged(app, event)
            [filter, ~] = app.applySelectedFilter;

            value = app.PlotTypeSwitch.Value;
            if strcmp (value, 'Time')
                app.filterTimeFrequency = 0;
            else 
                app.filterTimeFrequency = 1;
            end

            if app.filterTimeFrequency == 0
                [impResponse, t_imp] = impz(filter);
                stem(app.UIAxes_2, t_imp, impResponse);
                title(app.UIAxes_2, 'Filter Impulse Response');
                xlabel(app.UIAxes_2, 'Samples');
                ylabel(app.UIAxes_2, 'Amplitude');
            else
                % Plot the Frequency Response in Frequency Domain
                [h, w] = freqz(filter, 'half', 2048, app.fs);
                plot(app.UIAxes_2, w, 20*log10(abs(h)));
                title(app.UIAxes_2, 'Filter Frequency Response');
                xlabel(app.UIAxes_2, 'Frequency (Hz)');
                ylabel(app.UIAxes_2, 'Magnitude (dB)');
            end
        end

        % Button pushed function: PlayUnfilteredSoundButton
        function PlayUnfilteredSoundButtonPushed(app, event)
            if app.stateOfMod == 1
                [shifted_audio, ~] = app.AudioCalculation();
                app.modifiedAudioData = shifted_audio;
                sound(real(app.modifiedAudioData), app.fs2)
            else
                sound(app.audioData, app.fs)
            end
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            app.amplitudeControl = value;
            if value ~= 100
                app.stateOfMod = 1;
                app.OriginalButton.Value = 1;
                app.OriginalButton.Text = 'Modified';
            else
                app.stateOfMod = 0;
                app.OriginalButton.Value = 0;
                app.OriginalButton.Text = 'Original';
            end
        end

        % Value changed function: TimeWrapsSlider
        function TimeWrapsSliderValueChanged(app, event)
            value = app.TimeWrapsSlider.Value;
            app.timeShiftControl = value;
            if value ~= 0
                app.stateOfMod = 1;
                app.OriginalButton.Value = 1;
                app.OriginalButton.Text = 'Modified';
            else
                app.stateOfMod = 0;
                app.OriginalButton.Value = 0;
                app.OriginalButton.Text = 'Original';
            end

        end

        % Value changed function: PlotSwitch
        function PlotSwitchValueChanged(app, event)
            value = app.PlotSwitch.Value;
            if strcmp (value, 'Time')
                app.MODtimeFrequency = 0;
            else 
                app.MODtimeFrequency = 1;
            end
        end

        % Button pushed function: GenerateAudioPlotButton
        function GenerateAudioPlotButtonPushed(app, event)

            if app.stateOfMod == 0
               audio_to_plot = app.audioData;
               plotTitle = 'Original';
            else 
               [shifted_audio, L] = app.AudioCalculation();
               app.modifiedAudioData = shifted_audio;
               audio_to_plot = shifted_audio;
               plotTitle = 'Modified';
            end
            
            %shifted_audio_ft = fft(shifted_audio);
            %newlength = round(length(shifted_audio_ft) / app.speedControl);
            %modifiedaudio = shifted_audio_ft(1:newlength);
            %scaled_shifted_audio = ifft(modifiedaudio);

            %app.modifiedAudioData = shifted_audio;
            L = length(audio_to_plot);
            if (app.MODtimeFrequency == 0) 
                % Compute and display the time domain signal
                t = (0:length(app.audioData)-1)/app.fs2;
                plot(app.UIAxes, t, audio_to_plot);
                title(app.UIAxes, [plotTitle, ' Time Domain Signal']);
                xlabel(app.UIAxes, 'Time (seconds)');
                ylabel(app.UIAxes, 'Amplitude');
            else 
                % Compute and display the Fourier Transform
                P2 = abs(fftshift(fft(audio_to_plot)/L));
                f = app.fs2 * (-L/2:(L/2-1))/L; 
                plot(app.UIAxes, f, P2);
                title(app.UIAxes, [plotTitle, ' Frequency Spectrum']);
                xlabel(app.UIAxes, 'Frequency (Hz)');
                ylabel(app.UIAxes, 'Magnitude');
            end 
        end

        % Button pushed function: PlayFilteredSoundButton
        function PlayFilteredSoundButtonPushed(app, event)
            [~, filteredAudio] = app.applySelectedFilter;


            if app.stateOfMod == 0
                freq = app.fs;
            else 
                freq = app.fs2;
            end
            sound(real(filteredAudio), freq);
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            value = app.SpeedSpinner.Value;
            app.speedControl = value;
            app.fs2 = app.fs * value;
            if value ~= 1
                app.stateOfMod = 1;
                app.OriginalButton.Value = 1;
                app.OriginalButton.Text = 'Modified';
            else
                app.stateOfMod = 0;
                app.OriginalButton.Value = 0;
                app.OriginalButton.Text = 'Original';
            end

        end

        % Button pushed function: SaveModifedAudiotoFileButton
        function SaveModifedAudiotoFileButtonPushed(app, event)
            audiowrite('modified.wav', real(app.modifiedAudioData), app.fs2);
        end

        % Value changed function: NoiseSpinner
        function NoiseSpinnerValueChanged(app, event)
            value = app.NoiseSpinner.Value;
            app.noiseControl = value;
            if value ~= 0
                app.stateOfMod = 1;
                app.OriginalButton.Value = 1;
                app.OriginalButton.Text = 'Modified';
            else
                app.stateOfMod = 0;
                app.OriginalButton.Value = 0;
                app.OriginalButton.Text = 'Original';
            end

        end

        % Value changed function: OriginalButton
        function OriginalButtonValueChanged(app, event)
            value = app.OriginalButton.Value;
            app.stateOfMod = value;
            if value == 0
                app.OriginalButton.Text = "Original";
            else
                app.OriginalButton.Text = "Modified";
            end

        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            app.filter_type = app.FilterTypeDropDown.Value;
            [filter,~] = app.applySelectedFilter;
            
            if app.filterTimeFrequency == 0
                [impResponse, t_imp] = impz(filter);
                stem(app.UIAxes_2, t_imp, impResponse);
                title(app.UIAxes_2, 'Filter Impulse Response');
                xlabel(app.UIAxes_2, 'Samples');
                ylabel(app.UIAxes_2, 'Amplitude');
            else
                % Plot the Frequency Response in Frequency Domain
                [h, w] = freqz(filter, 'half', 2048, app.fs);
                plot(app.UIAxes_2, w, 20*log10(abs(h)));
                title(app.UIAxes_2, 'Filter Frequency Response');
                xlabel(app.UIAxes_2, 'Frequency (Hz)');
                ylabel(app.UIAxes_2, 'Magnitude (dB)');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filter')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.ECE45SynthesizerLabel = uilabel(app.UIFigure);
            app.ECE45SynthesizerLabel.Text = 'ECE 45 Synthesizer';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlotTypeLabel = uilabel(app.UIFigure);
            app.PlotTypeLabel.Text = '(Plot Type):';
            app.PlotTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotTypeSwitch.Items = {'Time', 'Frequency'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitchValueChanged, true);
            app.PlotTypeSwitch.Value = 'Time';
            app.FileLoadedLamp = uilamp(app.UIFigure);
            app.PlayUnfilteredSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayUnfilteredSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayUnfilteredSoundButtonPushed, true);
            app.PlayUnfilteredSoundButton.Text = 'Play Unfiltered Sound';
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Volume (%)';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [0 200];
            app.VolumeSlider.MajorTicks = [0 50 100 150 200];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 100;
            app.TimeWrapsSliderLabel = uilabel(app.UIFigure);
            app.TimeWrapsSliderLabel.Text = 'Time Wrap (s)';
            app.TimeWrapsSlider = uislider(app.UIFigure);
            app.TimeWrapsSlider.Limits = [-5 5];
            app.TimeWrapsSlider.MajorTicks = [-5 0 5];
            app.TimeWrapsSlider.ValueChangedFcn = createCallbackFcn(app, @TimeWrapsSliderValueChanged, true);
            app.TimeWrapsSlider.MinorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.SpeedSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.UIFigure);
            app.SpeedSpinner.Step = 0.1;
            app.SpeedSpinner.Limits = [0.1 4];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedSpinner.Value = 1;
            app.GenerateAudioPlotButton = uibutton(app.UIFigure, 'push');
            app.GenerateAudioPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAudioPlotButtonPushed, true);
            app.GenerateAudioPlotButton.Text = 'Generate Audio Plot';
            app.PlayFilteredSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredSoundButtonPushed, true);
            app.PlayFilteredSoundButton.Text = 'Play Filtered Sound';
            app.PlotSwitchLabel = uilabel(app.UIFigure);
            app.PlotSwitchLabel.Text = 'Plot';
            app.PlotSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotSwitch.Items = {'Time', 'Frequency'};
            app.PlotSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSwitchValueChanged, true);
            app.PlotSwitch.Value = 'Time';
            app.SaveModifedAudiotoFileButton = uibutton(app.UIFigure, 'push');
            app.SaveModifedAudiotoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModifedAudiotoFileButtonPushed, true);
            app.SaveModifedAudiotoFileButton.Text = 'Save Modifed Audio to File';
            app.NoiseSpinnerLabel = uilabel(app.UIFigure);
            app.NoiseSpinnerLabel.Text = 'Noise';
            app.NoiseSpinner = uispinner(app.UIFigure);
            app.NoiseSpinner.Limits = [0 10];
            app.NoiseSpinner.ValueChangedFcn = createCallbackFcn(app, @NoiseSpinnerValueChanged, true);
            app.OriginalButton = uibutton(app.UIFigure, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.OriginalButton.Text = 'Original';
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'Band Pass', 'High Pass', 'Low Pass', 'Band Reject'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Band Pass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE_45_Synthesizer_Noise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end