classdef ECE_45_Synthesizer_TimeShift < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlotTypeSwitch_2       matlab.ui.control.Switch
        PlotTypeSwitch_2Label  matlab.ui.control.Label
        PlaySoundButton_2      matlab.ui.control.Button
        GeneratePlotButton_2   matlab.ui.control.Button
        SpeedSpinner           matlab.ui.control.Spinner
        SpeedSpinnerLabel      matlab.ui.control.Label
        TimeDelaysSlider       matlab.ui.control.Slider
        TimeDelaysSliderLabel  matlab.ui.control.Label
        VolumeSlider           matlab.ui.control.Slider
        AmplitudeLabel         matlab.ui.control.Label
        PlaySoundButton        matlab.ui.control.Button
        FileLoadedLamp         matlab.ui.control.Lamp
        PlotTypeSwitch         matlab.ui.control.Switch
        PlotTypeLabel          matlab.ui.control.Label
        LoadFileButton         matlab.ui.control.Button
        GeneratePlotButton     matlab.ui.control.Button
        ECE45SynthesizerLabel  matlab.ui.control.Label
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        original_fs % Sampling rate of the loaded MP3 file
        mod_fs
        audioData % Audio data of the loaded MP3 file
        
        OGtimeFrequency = 0 % Plot original in time vs frequency
        amplitudeControl = 100; % Amplitude scaling percentage 
        timeShiftControl = 0; % Time shift in seconds
        speedControl = 1; % Playback speed multiplier
        MODtimeFrequency = 0;

        modifiedAudioData % Audio data with modifications
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Open file dialog for selecting an MP3 file
            [file, path] = uigetfile('*.mp3', 'Select an MP3 file');
            if isequal(file, 0)
                return; % User clicked Cancel
            end
            fullPath = fullfile(path, file);
        
            % Read the audio file
            [app.audioData, app.original_fs] = audioread(fullPath);
            app.FileLoadedLamp.Color = 'g';
        end

        % Value changed function: PlotTypeSwitch
        function PlotTypeSwitchValueChanged(app, event)
            value = app.PlotTypeSwitch.Value;
            if strcmp (value, 'Time')
                app.OGtimeFrequency = 0;
            else 
                app.OGtimeFrequency = 1;
            end
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
                if (app.OGtimeFrequency == 0) 
                    % Compute and display the time domain signal
                    t = (0:length(app.audioData)-1)/app.original_fs; % Time vector
            
                    plot(app.UIAxes, t, app.audioData);
                    title(app.UIAxes, 'Time Domain Signal');
                    xlabel(app.UIAxes, 'Time (seconds)');
                    ylabel(app.UIAxes, 'Amplitude');
                else 
                    % Compute and display the Fourier Transform
                    L = length(app.audioData); % Assuming app.audioData stores the audio data
                    Y = fft(app.audioData);
                    P2 = abs(fftshift(Y/L));
                    f = app.original_fs * (-L/2:(L/2-1))/L; % Assuming app.fs stores the sampling frequency
                    app.mod_fs = app.orignal_fs;

                    plot(app.UIAxes, f, P2);
                    title(app.UIAxes, 'Frequency Spectrum');
                    xlabel(app.UIAxes, 'Frequency (Hz)');
                    ylabel(app.UIAxes, 'Magnitude');
                end                
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            sound(app.audioData, app.original_fs)
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            app.amplitudeControl = value;
        end

        % Value changed function: TimeDelaysSlider
        function TimeDelaysSliderValueChanged(app, event)
            value = app.TimeDelaysSlider.Value;
            app.timeShiftControl = value;
        end

        % Value changed function: PlotTypeSwitch_2
        function PlotTypeSwitch_2ValueChanged(app, event)
            value = app.PlotTypeSwitch_2.Value;
            if strcmp (value, 'Time')
                app.MODtimeFrequency = 0;
            else 
                app.MODtimeFrequency = 1;
            end
        end

        % Button pushed function: GeneratePlotButton_2
        function GeneratePlotButton_2Pushed(app, event)

            L = length(app.audioData); % Assuming app.audioData stores the audio data
            Y = fft(app.audioData);
            
            modifiedY = Y*(app.amplitudeControl/100); % Make modifications here
            modifiedY_time = ifft(modifiedY);

            yt = fft(modifiedY_time);
            samples_to_shift = round(app.timeShiftControl * app.original_fs);
            if samples_to_shift > 0
                shift_vector = exp(-1j * 2 * pi * (0:length(modifiedY_time)-1)' * samples_to_shift / length(modifiedY_time));
            elseif samples_to_shift < 0
                shift_vector = exp(1j * 2 * pi * (0:length(modifiedY_time)-1)' * abs(samples_to_shift) / length(modifiedY_time));
            else
                shifted_audio = modifiedY_time;
                shift_vector = 1;
            end

            shifted_audio_fft = yt .* shift_vector;
            shifted_audio = ifft(shifted_audio_fft);

            if length(shifted_audio) < length(modifiedY_time)
                shifted_audio = [shifted_audio; zeros(length(audio) - length(shifted_audio), 1)];
            elseif length(shifted_audio) > length(modifiedY_time)
                shifted_audio = shifted_audio(1:length(modifiedY_time));
            end
            
            app.modifiedAudioData = shifted_audio;
            P2 = abs(fftshift(modifiedY/L));
            f = app.original_fs * (-L/2:(L/2-1))/L; % Assuming app.fs stores the sampling frequency
            if (app.MODtimeFrequency == 0) 
                % Compute and display the time domain signal
                t = (0:length(app.audioData)-1)/app.original_fs; % Time vector
        
                plot(app.UIAxes_2, t, shifted_audio);
                title(app.UIAxes_2, 'Modified Time Domain Signal');
                xlabel(app.UIAxes_2, 'Time (seconds)');
                ylabel(app.UIAxes_2, 'Amplitude');
            else 
                % Compute and display the Fourier Transform
                plot(app.UIAxes_2, f, P2);
                title(app.UIAxes_2, 'Modified Frequency Spectrum');
                xlabel(app.UIAxes_2, 'Frequency (Hz)');
                ylabel(app.UIAxes_2, 'Magnitude');
            end 
        end

        % Button pushed function: PlaySoundButton_2
        function PlaySoundButton_2Pushed(app, event)
            %Y = fft(app.audioData);
            %modifiedY = Y*(app.amplitudeControl/100);
            %modifiedY_time = ifft(modifiedY);
            %app.modifiedAudioData = modifiedY_time;
            
            sound(real(app.modifiedAudioData), app.mod_fs)
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            value = app.SpeedSpinner.Value; % This is the speed factor
    
            % Adjust the playback speed
            app.mod_fs = app.original_fs * value; % Adjust the sampling rate
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Modified')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.ECE45SynthesizerLabel = uilabel(app.UIFigure);
            app.ECE45SynthesizerLabel.Text = 'ECE 45 Synthesizer';
            app.GeneratePlotButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlotTypeLabel = uilabel(app.UIFigure);
            app.PlotTypeLabel.Text = '(Plot Type):';
            app.PlotTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotTypeSwitch.Items = {'Time', 'Frequency'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitchValueChanged, true);
            app.PlotTypeSwitch.Value = 'Time';
            app.FileLoadedLamp = uilamp(app.UIFigure);
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Volume (%)';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [0 200];
            app.VolumeSlider.MajorTicks = [0 50 100 150 200];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 100;
            app.TimeDelaysSliderLabel = uilabel(app.UIFigure);
            app.TimeDelaysSliderLabel.Text = 'Time Delay (s)';
            app.TimeDelaysSlider = uislider(app.UIFigure);
            app.TimeDelaysSlider.Limits = [-10 10];
            app.TimeDelaysSlider.MajorTicks = [-10 -5 0 5 10];
            app.TimeDelaysSlider.ValueChangedFcn = createCallbackFcn(app, @TimeDelaysSliderValueChanged, true);
            app.SpeedSpinnerLabel = uilabel(app.UIFigure);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.UIFigure);
            app.SpeedSpinner.Step = 0.1;
            app.SpeedSpinner.Limits = [0.1 10];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedSpinner.Value = 1;
            app.GeneratePlotButton_2 = uibutton(app.UIFigure, 'push');
            app.GeneratePlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButton_2Pushed, true);
            app.GeneratePlotButton_2.Text = 'Generate Plot';
            app.PlaySoundButton_2 = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButton_2Pushed, true);
            app.PlaySoundButton_2.Text = 'Play Sound';
            app.PlotTypeSwitch_2Label = uilabel(app.UIFigure);
            app.PlotTypeSwitch_2Label.Text = '(Plot Type):';
            app.PlotTypeSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.PlotTypeSwitch_2.Items = {'Time', 'Frequency'};
            app.PlotTypeSwitch_2.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitch_2ValueChanged, true);
            app.PlotTypeSwitch_2.Value = 'Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE_45_Synthesizer_TimeShift

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end