classdef SynthesizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        LoadMP3Button          matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        FrequencyDomainButton  matlab.ui.control.StateButton
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fs % Sampling rate of the loaded MP3 file
        audioData % Audio data of the loaded MP3 file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrequencyDomainButton
        function FrequencyDomainButtonValueChanged(app, event)
            value = app.FrequencyDomainButton.Value;
            if value
                % Switch to Frequency Domain
                app.FrequencyDomainButton.Text = 'Switch to Time Domain';
        
                % Compute and display the Fourier Transform
                L = length(app.audioData); % Assuming app.audioData stores the audio data
                Y = fft(app.audioData);
                P2 = abs(fftshift(Y/L));
                f = app.fs * (-L/2:(L/2-1))/L; % Assuming app.fs stores the sampling frequency
        
                plot(app.UIAxes, f, P2);
                title(app.UIAxes, 'Frequency Spectrum');
                xlabel(app.UIAxes, 'Frequency (Hz)');
                ylabel(app.UIAxes, 'Magnitude');
            else
                % Switch to Time Domain
                app.FrequencyDomainButton.Text = 'Switch to Frequency Domain';
        
                % Compute and display the time domain signal
                t = (0:length(app.audioData)-1)/app.fs; % Time vector
        
                plot(app.UIAxes, t, app.audioData);
                title(app.UIAxes, 'Time Domain Signal');
                xlabel(app.UIAxes, 'Time (seconds)');
                ylabel(app.UIAxes, 'Amplitude');
            end
        end

        % Button pushed function: LoadMP3Button
        function LoadMP3ButtonPushed(app, event)
             % Open file dialog for selecting an MP3 file
            [file, path] = uigetfile('*.mp3', 'Select an MP3 file');
            if isequal(file, 0)
                return; % User clicked Cancel
            end
            fullPath = fullfile(path, file);
        
            % Read the audio file
            [app.audioData, app.fs] = audioread(fullPath);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadMP3Button = uibutton(app.LeftPanel, 'push');
            app.LoadMP3Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMP3ButtonPushed, true);
            app.LoadMP3Button.Text = 'Load MP3';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrequencyDomainButton = uibutton(app.RightPanel, 'state');
            app.FrequencyDomainButton.ValueChangedFcn = createCallbackFcn(app, @FrequencyDomainButtonValueChanged, true);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.FrequencyDomainButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynthesizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end